classdef gui_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EtchASketchUploaderUIFigure    matlab.ui.Figure
        ResolutionSliderLabel          matlab.ui.control.Label
        ResolutionSlider               matlab.ui.control.Slider
        PreviewButton                  matlab.ui.control.Button
        OrientationDropDownLabel       matlab.ui.control.Label
        OrientationDropDown            matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
        ContrastSliderLabel            matlab.ui.control.Label
        ContrastSlider                 matlab.ui.control.Slider
        PrintButton                    matlab.ui.control.Button
        UploadImageButton              matlab.ui.control.Button
        EtchASketchUploaderLabel       matlab.ui.control.Label
        CheckBox                       matlab.ui.control.CheckBox
        Label                          matlab.ui.control.Label
        Resnumber                      matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        Tolnumber                      matlab.ui.control.NumericEditField
        Team12RyanMaziarzJamesNellisandRuijiLiuLabel  matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        EtchASketchPortEditFieldLabel  matlab.ui.control.Label
        EtchASketchPortEditField       matlab.ui.control.NumericEditField
        Button                         matlab.ui.control.Button
    end

    
    properties (Access = public)
        image
        Comppath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            close all
            figure(app.EtchASketchUploaderUIFigure)
            resolution = app.ResolutionSlider.Value;
            orientation = app.OrientationDropDown.Value;
            tolerance = app.ContrastSlider.Value;
            if app.CheckBox.Value == 1
                [preview] = ImageTranslator(app.image,resolution,orientation,tolerance);
                app.Comppath = preview;
                
            end
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            app.image = [];
            [file,path] = uigetfile({'*.png;*.jpg','Select A File'});
            if path~=0
                cd(path);
                app.image = imread([path file]);
                app.CheckBox.Value = 1;
                app.CheckBox.Tooltip = {'Image successfully uploaded'; ''};
                imshow(app.image,'parent',app.UIAxes);
                if app.EtchASketchPortEditField.Value ~=0
                    app.PrintButton.Enable = 'on';
                end
            end
            figure(app.EtchASketchUploaderUIFigure)
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            value = app.ContrastSlider.Value;
            app.Tolnumber.Value = value;
        end

        % Value changed function: ResolutionSlider
        function ResolutionSliderValueChanged(app, event)
            value = app.ResolutionSlider.Value;
            app.Resnumber.Value = value;
        end

        % Value changed function: Resnumber
        function ResnumberValueChanged(app, event)
            value = app.Resnumber.Value;
            app.ResolutionSlider.Value = value;
        end

        % Value changed function: Tolnumber
        function TolnumberValueChanged(app, event)
            value = app.Tolnumber.Value;
            app.ContrastSlider.Value = value;
        end

        % Button pushed function: PrintButton
        function PrintButtonPushed(app, event)
            basecode(app.Comppath,app.EtchASketchPortEditField.Value)
        end

        % Button pushed function: Button
        function HelpButton(app, event)
            open helpguide.pdf
        end

        % Value changed function: EtchASketchPortEditField
        function EtchASketchPortEditFieldValueChanged(app, event)
            value = app.EtchASketchPortEditField.Value;
            if value ~=0 && app.CheckBox.Value==1
                    app.PrintButton.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EtchASketchUploaderUIFigure = uifigure('Visible', 'off');
            app.EtchASketchUploaderUIFigure.Name = 'Etch-A-Sketch Uploader';
            app.ResolutionSliderLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.ResolutionSliderLabel.Text = 'Resolution';
            app.ResolutionSlider = uislider(app.EtchASketchUploaderUIFigure);
            app.ResolutionSlider.ValueChangedFcn = createCallbackFcn(app, @ResolutionSliderValueChanged, true);
            app.ResolutionSlider.Tooltip = {'Control the number of pixels used'};
            app.ResolutionSlider.Value = 100;
            app.PreviewButton = uibutton(app.EtchASketchUploaderUIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Tooltip = {'Preview the image prior to printing'};
            app.PreviewButton.Text = {'Preview'; ''};
            app.OrientationDropDownLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.OrientationDropDownLabel.Text = {'Orientation'; ''};
            app.OrientationDropDown = uidropdown(app.EtchASketchUploaderUIFigure);
            app.OrientationDropDown.Items = {'Down', 'Up', 'Left', 'Right'};
            app.OrientationDropDown.Tooltip = {'Control which direction the bottom of the image faces'};
            app.OrientationDropDown.Value = 'Down';
            app.UIAxes = uiaxes(app.EtchASketchUploaderUIFigure);
            title(app.UIAxes, 'User Image    ')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.ContrastSliderLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.EtchASketchUploaderUIFigure);
            app.ContrastSlider.Limits = [0 2];
            app.ContrastSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Tooltip = {'Control the contrast of the image.'};
            app.ContrastSlider.Value = 1;
            app.PrintButton = uibutton(app.EtchASketchUploaderUIFigure, 'push');
            app.PrintButton.ButtonPushedFcn = createCallbackFcn(app, @PrintButtonPushed, true);
            app.PrintButton.Enable = 'off';
            app.PrintButton.Tooltip = {'Print the image on the Etch-A-Sketch'};
            app.PrintButton.Text = {'Print'; ''};
            app.UploadImageButton = uibutton(app.EtchASketchUploaderUIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Tooltip = {'Upload an image to print'};
            app.UploadImageButton.Text = 'Upload Image';
            app.EtchASketchUploaderLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.EtchASketchUploaderLabel.Text = 'Etch-A-Sketch Uploader ';
            app.CheckBox = uicheckbox(app.EtchASketchUploaderUIFigure);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Tooltip = {'No image uploaded'};
            app.CheckBox.Text = '';
            app.Label = uilabel(app.EtchASketchUploaderUIFigure);
            app.Label.Text = '';
            app.Resnumber = uieditfield(app.EtchASketchUploaderUIFigure, 'numeric');
            app.Resnumber.Limits = [0 100];
            app.Resnumber.ValueDisplayFormat = '%.2f';
            app.Resnumber.ValueChangedFcn = createCallbackFcn(app, @ResnumberValueChanged, true);
            app.Resnumber.Value = 100;
            app.Label_2 = uilabel(app.EtchASketchUploaderUIFigure);
            app.Label_2.Text = '';
            app.Tolnumber = uieditfield(app.EtchASketchUploaderUIFigure, 'numeric');
            app.Tolnumber.Limits = [0 2];
            app.Tolnumber.ValueDisplayFormat = '%.2f';
            app.Tolnumber.ValueChangedFcn = createCallbackFcn(app, @TolnumberValueChanged, true);
            app.Tolnumber.Value = 1;
            app.Team12RyanMaziarzJamesNellisandRuijiLiuLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.Team12RyanMaziarzJamesNellisandRuijiLiuLabel.Text = {'Team 1.2 Ryan Maziarz, James Nellis, and Ruiji Liu'; ''};
            app.Label_3 = uilabel(app.EtchASketchUploaderUIFigure);
            app.Label_3.Text = '2021';
            app.EtchASketchPortEditFieldLabel = uilabel(app.EtchASketchUploaderUIFigure);
            app.EtchASketchPortEditFieldLabel.Text = 'Etch-A-Sketch Port';
            app.EtchASketchPortEditField = uieditfield(app.EtchASketchUploaderUIFigure, 'numeric');
            app.EtchASketchPortEditField.ValueChangedFcn = createCallbackFcn(app, @EtchASketchPortEditFieldValueChanged, true);
            app.EtchASketchPortEditField.Tooltip = {'Enter the COM Port Number'};
            app.Button = uibutton(app.EtchASketchUploaderUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @HelpButton, true);
            app.Button.Tooltip = {'Help'};
            app.Button.Text = '?';
            % Show the figure after all components are created
            app.EtchASketchUploaderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EtchASketchUploaderUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EtchASketchUploaderUIFigure)
        end
    end
end